classdef GUI_v4_1_InitialConditions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SPOT41InitialConditionsUIFigure  matlab.ui.Figure
        GridLayout25               matlab.ui.container.GridLayout
        VisualizerPanel            matlab.ui.container.Panel
        GridLayout26               matlab.ui.container.GridLayout
        ShowDropLocationButton     matlab.ui.control.Button
        ShowInitialLocationButton  matlab.ui.control.Button
        ShowHomeLocationButton     matlab.ui.control.Button
        ExperimentPreview          matlab.ui.control.UIAxes
        SetInitialandGoalPositionsforPlatformsPanel  matlab.ui.container.Panel
        GridLayout24_2             matlab.ui.container.GridLayout
        DurPhase0Units_65          matlab.ui.control.Label
        DurPhase0Units_64          matlab.ui.control.Label
        DurPhase0Units_63          matlab.ui.control.Label
        DurPhase0Units_62          matlab.ui.control.Label
        DurPhase0Units_61          matlab.ui.control.Label
        DurPhase0Units_60          matlab.ui.control.Label
        DurPhase0Units_59          matlab.ui.control.Label
        DurPhase0Units_58          matlab.ui.control.Label
        DurPhase0Units_57          matlab.ui.control.Label
        DurPhase0Units_56          matlab.ui.control.Label
        DurPhase0Units_55          matlab.ui.control.Label
        Image_3                    matlab.ui.control.Image
        Image_2                    matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        BLUEStartTh                matlab.ui.control.NumericEditField
        DurPhase0Units_45          matlab.ui.control.Label
        BLUEStartY                 matlab.ui.control.NumericEditField
        DurPhase0Units_44          matlab.ui.control.Label
        BLUEStartX                 matlab.ui.control.NumericEditField
        mathrmInitial_BLULabel     matlab.ui.control.Label
        BLUEInitialTh              matlab.ui.control.NumericEditField
        DurPhase0Units_36          matlab.ui.control.Label
        BLUEInitialY               matlab.ui.control.NumericEditField
        DurPhase0Units_35          matlab.ui.control.Label
        BLUEInitialX               matlab.ui.control.NumericEditField
        mathrmDrop_BLULabel        matlab.ui.control.Label
        BLACKInitialTh             matlab.ui.control.NumericEditField
        DurPhase0Units_33          matlab.ui.control.Label
        BLACKInitialY              matlab.ui.control.NumericEditField
        DurPhase0Units_32          matlab.ui.control.Label
        BLACKInitialX              matlab.ui.control.NumericEditField
        mathrmDrop_BLALabel        matlab.ui.control.Label
        ARMInitialQ3               matlab.ui.control.NumericEditField
        ARMInitialQ2               matlab.ui.control.NumericEditField
        ARMInitialQ1               matlab.ui.control.NumericEditField
        mathrmDrop_ARMLabel        matlab.ui.control.Label
        mathrmq_3Label             matlab.ui.control.Label
        mathrmq_2Label             matlab.ui.control.Label
        mathrmq_1Label             matlab.ui.control.Label
        REDHomeTh                  matlab.ui.control.NumericEditField
        BLUEHomeTh                 matlab.ui.control.NumericEditField
        DurPhase0Units_54          matlab.ui.control.Label
        BLUEHomeY                  matlab.ui.control.NumericEditField
        DurPhase0Units_53          matlab.ui.control.Label
        BLUEHomeX                  matlab.ui.control.NumericEditField
        mathrmHome_BLULabel        matlab.ui.control.Label
        BLACKHomeTh                matlab.ui.control.NumericEditField
        DurPhase0Units_51          matlab.ui.control.Label
        BLACKHomeY                 matlab.ui.control.NumericEditField
        DurPhase0Units_50          matlab.ui.control.Label
        BLACKHomeX                 matlab.ui.control.NumericEditField
        mathrmHome_BLALabel        matlab.ui.control.Label
        DurPhase0Units_48          matlab.ui.control.Label
        DurPhase0Units_47          matlab.ui.control.Label
        REDHomeY                   matlab.ui.control.NumericEditField
        REDHomeX                   matlab.ui.control.NumericEditField
        mathrmHome_REDLabel        matlab.ui.control.Label
        BLACKStartTh               matlab.ui.control.NumericEditField
        DurPhase0Units_42          matlab.ui.control.Label
        BLACKStartY                matlab.ui.control.NumericEditField
        DurPhase0Units_41          matlab.ui.control.Label
        BLACKStartX                matlab.ui.control.NumericEditField
        mathrmInitial_BLALabel     matlab.ui.control.Label
        DurPhase0Units_39          matlab.ui.control.Label
        DurPhase0Units_38          matlab.ui.control.Label
        REDStartY                  matlab.ui.control.NumericEditField
        REDStartTh                 matlab.ui.control.NumericEditField
        REDStartX                  matlab.ui.control.NumericEditField
        mathrmInitial_REDLabel     matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        mathrmtheta_baseLabel      matlab.ui.control.Label
        mathrmYLabel               matlab.ui.control.Label
        mathrmXLabel               matlab.ui.control.Label
        DurPhase0Units_31          matlab.ui.control.Label
        DurPhase0Units_30          matlab.ui.control.Label
        DurPhase0Units_8           matlab.ui.control.Label
        REDInitialTh               matlab.ui.control.NumericEditField
        REDInitialY                matlab.ui.control.NumericEditField
        REDInitialX                matlab.ui.control.NumericEditField
        mathrmDrop_REDLabel        matlab.ui.control.Label
    end

    
properties (Access = public)
            
        init_states_RED;          
        init_states_BLACK;
        init_states_BLUE;        
        
        drop_states_ARM;
        drop_states_RED;   
        drop_states_BLACK;      
        drop_states_BLUE; 
        
        home_states_RED;  
        home_states_BLACK;  
        home_states_BLUE;       

        xLength                        = 3.51155;   % [m]
        yLength                        = 2.41935;   % [m]

        REDFigHdl;
        BLACKFigHdl;
        BLUEFigHdl;
        REDFigLine;
        BLACKFigLine;
        BLUEFigLine;
        REDFigLink1;
        REDFigLink2;
        REDFigLink3;
        REDFigJoin0;
        REDFigJoin1;
        REDFigJoin2;
        REDFigJoin3;

        Phi;
        b0;
        a1;
        b1;
        a2;
        b2;
        a3;
        b3;

        MainGUI;

        DraggingObjectHandle % Handle to the object being dragged
        DraggingOffset       % Offset between mouse and object position

    end

    methods (Access = public)

        % This function creates a custom waitbox to force the app to pause
        % while it is processing a user request.
        function d = CustomWaitBox(app,Message)
            fig = app.SPOT41InitialConditionsUIFigure;
            d = uiprogressdlg(fig,'Title',Message,...
                'Indeterminate','on');
            drawnow
                   
        end

        function [] = UpdateInitialConditions(app)
            
            app.init_states_RED      = [app.REDStartX.Value,app.REDStartY.Value,app.REDStartTh.Value*pi/180];
            app.init_states_BLACK    = [app.BLACKStartX.Value,app.BLACKStartY.Value,app.BLACKStartTh.Value*pi/180];
            app.init_states_BLUE     = [app.BLUEStartX.Value,app.BLUEStartY.Value,app.BLUEStartTh.Value*pi/180];

            app.drop_states_RED      = [app.REDInitialX.Value,app.REDInitialY.Value,app.REDInitialTh.Value*pi/180];
            app.drop_states_BLACK    = [app.BLACKInitialX.Value,app.BLACKInitialY.Value,app.BLACKInitialTh.Value*pi/180];
            app.drop_states_BLUE     = [app.BLUEInitialX.Value,app.BLUEInitialY.Value,app.BLUEInitialTh.Value*pi/180];
            app.drop_states_ARM      = [app.ARMInitialQ1.Value*pi/180,app.ARMInitialQ2.Value*pi/180,app.ARMInitialQ3.Value*pi/180];

            app.home_states_RED      = [app.REDHomeX.Value,app.REDHomeY.Value,app.REDHomeTh.Value*pi/180];
            app.home_states_BLACK    = [app.BLACKHomeX.Value,app.BLACKHomeY.Value,app.BLACKHomeTh.Value*pi/180];
            app.home_states_BLUE     = [app.BLUEHomeX.Value,app.BLUEHomeY.Value,app.BLUEHomeTh.Value*pi/180];

            assignin('base', 'init_states_RED', app.init_states_RED);
            assignin('base', 'init_states_BLACK', app.init_states_BLACK);
            assignin('base', 'init_states_BLUE', app.init_states_BLUE);
            assignin('base', 'drop_states_RED', app.drop_states_RED);
            assignin('base', 'drop_states_BLACK', app.drop_states_BLACK);
            assignin('base', 'drop_states_BLUE', app.drop_states_BLUE);
            assignin('base', 'drop_states_ARM', app.drop_states_ARM);
            assignin('base', 'home_states_RED', app.home_states_RED);
            assignin('base', 'home_states_BLACK', app.home_states_BLACK);
            assignin('base', 'home_states_BLUE', app.home_states_BLUE);

            app.MainGUI.ConfirmSettings();

        end

                % This function creates a rectangle in a plot. 
        function [FigHdl] = DrawRectanglePrivateViz(app,param,style)
            
            if (nargin <1)
                error("Please see help for INPUT DATA.");
            elseif (nargin==1)
                style="b";
            end
            
            [m,n] = size(param);
            
            if(m ~= 1 || n ~= 5)
                error("param should be an 1x5 array.");
            end
            
            if(param(3) <=0 || param(4) <=0)
                error("width and height must be positive values.");
            end
            
            a = param(1);
            b = param(2);
            w = param(3);
            h = param(4);
            theta = param(5);
            
            X = [-w/2 w/2 w/2 -w/2 -w/2];
            Y = [h/2 h/2 -h/2 -h/2 h/2];
            P = [X;Y];
            ct = cos(theta);
            st = sin(theta);
            R = [ct -st;st ct];
            P = R * P;

            spacecraft = app.DrawSpacecraft(param);

            patch(app.ExperimentPreview,spacecraft(:,1), spacecraft(:,2),style,'FaceAlpha',0.3);
            
            FigHdl = plot(app.ExperimentPreview,P(1,:)+a,P(2,:)+b,style);  

            % Assign ButtonDownFcn for dragging
            FigHdl.ButtonDownFcn = @(src, event) startDragging(app, src, event);

        
        end   

        
        function [] = AddPlatformViz(app,platformSelection, drawState_RED, drawState_BLACK, drawState_BLUE, drawState_ARM)

            app.b0 = evalin("base","b0");
            app.a1 = evalin("base","a1");
            app.b1 = evalin("base","b1");
            app.a2 = evalin("base","a2");
            app.b2 = evalin("base","b2");
            app.a3 = evalin("base","a3");
            app.b3 = evalin("base","b3");
            app.Phi = evalin("base","Phi");
            
            cla(app.ExperimentPreview);
            app.REDFigHdl = []; 
            app.BLACKFigHdl = [];
            app.BLUEFigHdl = [];
            app.REDFigLine  = [];
            app.BLACKFigLine= [];
            app.BLUEFigLine = [];
            app.REDFigLink1 = [];
            app.REDFigLink2 = [];
            app.REDFigLink3 = [];
            app.REDFigJoin0 = [];
            app.REDFigJoin1 = [];
            app.REDFigJoin2 = [];
            app.REDFigJoin3 = [];

            % Initialize the components of the GUI:
            app.xLength = 3.51155;  %[m]
            app.yLength = 2.41935;  %[m]
                        
            % Flip the axis to match the table:
            axis(app.ExperimentPreview,"ij");
            
            % Set the remaining axes properties:
            app.ExperimentPreview.XAxisLocation = "top";
            app.ExperimentPreview.XLim          = [-0.65 3];
            app.ExperimentPreview.YLim          = [-0.5 4];
            
            % Plot the table:
            line(app.ExperimentPreview,[0 app.yLength],[0 0],"Color","black","LineWidth",2);
            hold(app.ExperimentPreview,"on");
            line(app.ExperimentPreview,[app.yLength app.yLength],[0 app.xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreview,[app.yLength 0],[app.xLength app.xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreview,[0 0],[app.xLength 0],"Color","black","LineWidth",2);
            grid(app.ExperimentPreview,"on");    

            % Add a patch
            patch(app.ExperimentPreview, [0, app.yLength, app.yLength, 0], ...
                                        [0, 0, app.xLength, app.xLength], ...
                                        'black', 'FaceAlpha', 0.1); 
            
            % For animations purposes, invert the angles:
            drawState_RED(3)  = -drawState_RED(3);
            drawState_BLACK(3)= -drawState_BLACK(3);
            drawState_BLUE(3) = -drawState_BLUE(3);
            
            % Draw the line showing the direction of the front face:
            C_bI_RED = [  cos(drawState_RED(3)) sin(drawState_RED(3))
                         -sin(drawState_RED(3)) cos(drawState_RED(3)) ];
                     
            Vec_RED  = C_bI_RED*[ 0.15
                                  0.0 ];
                              
            C_bI_BLACK = [  cos(drawState_BLACK(3)) sin(drawState_BLACK(3))
                           -sin(drawState_BLACK(3)) cos(drawState_BLACK(3)) ];
                     
            Vec_BLACK  = C_bI_BLACK*[ 0.15
                                      0.0 ];    
                              
            C_bI_BLUE = [  cos(drawState_BLUE(3)) sin(drawState_BLUE(3))
                          -sin(drawState_BLUE(3)) cos(drawState_BLUE(3)) ];
                     
            Vec_BLUE  = C_bI_BLUE*[ 0.15
                                    0.0 ];                              
            x0 = drawState_RED(2);
            y0 = drawState_RED(1);
            q0 = drawState_RED(3);
            q1 = (-drawState_ARM(1) + 3*pi/2);
            q2 = -drawState_ARM(2);
            q3 = -drawState_ARM(3);        
            
            rs                      = [ x0 + (app.b0)*cos(pi/2-app.Phi + q0),y0 + (app.b0)*sin(pi/2-app.Phi + q0) ];                        
                        
            re                      = [ x0 + (app.b0)*cos(pi/2-app.Phi + q0) + (app.a1+app.b1)*cos(pi/2 + q0 + q1),y0 + (app.b0)*sin(pi/2-app.Phi + q0) + (app.a1+app.b1)*sin(pi/2 + q0 + q1) ];

            rw                      = [ x0 + (app.b0)*cos(pi/2-app.Phi + q0) + (app.a1+app.b1)*cos(pi/2 + q0 + q1) + (app.a2+app.b2)*cos(pi/2 + q0 + q1 + q2),y0 + (app.b0)*sin(pi/2-app.Phi + q0) + (app.a1+app.b1)*sin(pi/2 + q0 + q1) + (app.a2+app.b2)*sin(pi/2 + q0 + q1 + q2) ];

            ree                     = [ x0 + (app.b0)*cos(pi/2-app.Phi + q0) + (app.a1+app.b1)*cos(pi/2 + q0 + q1) + (app.a2+app.b2)*cos(pi/2 + q0 + q1 + q2) + (app.a3+app.b3)*cos(pi/2 + q0 + q1 + q2 + q3),y0 + (app.b0)*sin(pi/2-app.Phi + q0) + (app.a1+app.b1)*sin(pi/2 + q0 + q1) + (app.a2+app.b2)*sin(pi/2 + q0 + q1 + q2) + (app.a3+app.b3)*sin(pi/2 + q0 + q1 + q2 + q3)];
                                
                                
            % Draw the relevant platforms:
            if (platformSelection == 1)
                
                app.REDFigHdl = DrawRectanglePrivateViz(app,[drawState_RED(2),drawState_RED(1),0.3,0.3,drawState_RED(3)],"r");
                app.REDFigLine = line(app.ExperimentPreview,[drawState_RED(2),drawState_RED(2)+Vec_RED(2)],[drawState_RED(1),drawState_RED(1)+Vec_RED(1)],'Color','red');
                                
            elseif (platformSelection == 2)
                
                app.BLACKFigHdl = DrawRectanglePrivateViz(app,[drawState_BLACK(2),drawState_BLACK(1),0.3,0.3,drawState_BLACK(3)],"k");   
                app.BLACKFigLine = line(app.ExperimentPreview,[drawState_BLACK(2),drawState_BLACK(2)+Vec_BLACK(2)],[drawState_BLACK(1),drawState_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                
            elseif (platformSelection == 3)
                
                app.REDFigHdl = DrawRectanglePrivateViz(app,[drawState_RED(2),drawState_RED(1),0.3,0.3,drawState_RED(3)],"r");
                app.BLACKFigHdl = DrawRectanglePrivateViz(app,[drawState_BLACK(2),drawState_BLACK(1),0.3,0.3,drawState_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drawState_RED(2),drawState_RED(2)+Vec_RED(2)],[drawState_RED(1),drawState_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drawState_BLACK(2),drawState_BLACK(2)+Vec_BLACK(2)],[drawState_BLACK(1),drawState_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                
            elseif (platformSelection == 4)
                
                app.REDFigHdl = DrawRectanglePrivateViz(app,[drawState_RED(2),drawState_RED(1),0.3,0.3,drawState_RED(3)],"r");
                app.BLACKFigHdl = DrawRectanglePrivateViz(app,[drawState_BLACK(2),drawState_BLACK(1),0.3,0.3,drawState_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drawState_RED(2),drawState_RED(2)+Vec_RED(2)],[drawState_RED(1),drawState_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drawState_BLACK(2),drawState_BLACK(2)+Vec_BLACK(2)],[drawState_BLACK(1),drawState_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.REDFigLink1 = line(app.ExperimentPreview,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(app.ExperimentPreview,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(app.ExperimentPreview,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(app.ExperimentPreview,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(app.ExperimentPreview,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(app.ExperimentPreview,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(app.ExperimentPreview,ree(1,1),ree(1,2),'filled','k'); 
                
            elseif (platformSelection == 5)
                
                app.REDFigHdl = DrawRectanglePrivateViz(app,[drawState_RED(2),drawState_RED(1),0.3,0.3,drawState_RED(3)],"r");
                app.REDFigLine = line(app.ExperimentPreview,[drawState_RED(2),drawState_RED(2)+Vec_RED(2)],[drawState_RED(1),drawState_RED(1)+Vec_RED(1)],'Color','red');
                app.REDFigLink1 = line(app.ExperimentPreview,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(app.ExperimentPreview,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(app.ExperimentPreview,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(app.ExperimentPreview,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(app.ExperimentPreview,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(app.ExperimentPreview,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(app.ExperimentPreview,ree(1,1),ree(1,2),'filled','k');
                
            elseif (platformSelection == 6)
                
                app.BLUEFigHdl = DrawRectanglePrivateViz(app,[drawState_BLUE(2),drawState_BLUE(1),0.3,0.3,drawState_BLUE(3)],"b");
                app.BLUEFigLine = line(app.ExperimentPreview,[drawState_BLUE(2),drawState_BLUE(2)+Vec_BLUE(2)],[drawState_BLUE(1),drawState_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 7)
                
                app.BLUEFigHdl = DrawRectanglePrivateViz(app,[drawState_BLUE(2),drawState_BLUE(1),0.3,0.3,drawState_BLUE(3)],"b");
                app.REDFigHdl = DrawRectanglePrivateViz(app,[drawState_RED(2),drawState_RED(1),0.3,0.3,drawState_RED(3)],"r");
                app.REDFigLine = line(app.ExperimentPreview,[drawState_RED(2),drawState_RED(2)+Vec_RED(2)],[drawState_RED(1),drawState_RED(1)+Vec_RED(1)],'Color','red');
                app.BLUEFigLine = line(app.ExperimentPreview,[drawState_BLUE(2),drawState_BLUE(2)+Vec_BLUE(2)],[drawState_BLUE(1),drawState_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 8)
                
                app.REDFigHdl = DrawRectanglePrivateViz(app,[drawState_RED(2),drawState_RED(1),0.3,0.3,drawState_RED(3)],"r");
                app.BLUEFigHdl = DrawRectanglePrivateViz(app,[drawState_BLUE(2),drawState_BLUE(1),0.3,0.3,drawState_BLUE(3)],"b");         
                app.BLACKFigHdl = DrawRectanglePrivateViz(app,[drawState_BLACK(2),drawState_BLACK(1),0.3,0.3,drawState_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drawState_RED(2),drawState_RED(2)+Vec_RED(2)],[drawState_RED(1),drawState_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drawState_BLACK(2),drawState_BLACK(2)+Vec_BLACK(2)],[drawState_BLACK(1),drawState_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(app.ExperimentPreview,[drawState_BLUE(2),drawState_BLUE(2)+Vec_BLUE(2)],[drawState_BLUE(1),drawState_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 9)
                
                app.BLUEFigHdl = DrawRectanglePrivateViz(app,[drawState_BLUE(2),drawState_BLUE(1),0.3,0.3,drawState_BLUE(3)],"b");         
                app.BLACKFigHdl = DrawRectanglePrivateViz(app,[drawState_BLACK(2),drawState_BLACK(1),0.3,0.3,drawState_BLACK(3)],"k"); 
                app.BLACKFigLine = line(app.ExperimentPreview,[drawState_BLACK(2),drawState_BLACK(2)+Vec_BLACK(2)],[drawState_BLACK(1),drawState_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(app.ExperimentPreview,[drawState_BLUE(2),drawState_BLUE(2)+Vec_BLUE(2)],[drawState_BLUE(1),drawState_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                                   
            elseif (platformSelection == 10)
                
                app.BLUEFigHdl = DrawRectanglePrivateViz(app,[drawState_BLUE(2),drawState_BLUE(1),0.3,0.3,drawState_BLUE(3)],"b"); 
                app.REDFigHdl = DrawRectanglePrivateViz(app,[drawState_RED(2),drawState_RED(1),0.3,0.3,drawState_RED(3)],"r"); 
                app.REDFigLine = line(app.ExperimentPreview,[drawState_RED(2),drawState_RED(2)+Vec_RED(2)],[drawState_RED(1),drawState_RED(1)+Vec_RED(1)],'Color','red');
                app.BLUEFigLine = line(app.ExperimentPreview,[drawState_BLUE(2),drawState_BLUE(2)+Vec_BLUE(2)],[drawState_BLUE(1),drawState_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                app.REDFigLink1 = line(app.ExperimentPreview,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(app.ExperimentPreview,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(app.ExperimentPreview,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(app.ExperimentPreview,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(app.ExperimentPreview,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(app.ExperimentPreview,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(app.ExperimentPreview,ree(1,1),ree(1,2),'filled','k'); 
                
            elseif (platformSelection == 11)
                
                app.REDFigHdl = DrawRectanglePrivateViz(app,[drawState_RED(2),drawState_RED(1),0.3,0.3,drawState_RED(3)],"r");
                app.BLUEFigHdl = DrawRectanglePrivateViz(app,[drawState_BLUE(2),drawState_BLUE(1),0.3,0.3,drawState_BLUE(3)],"b");         
                app.BLACKFigHdl = DrawRectanglePrivateViz(app,[drawState_BLACK(2),drawState_BLACK(1),0.3,0.3,drawState_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drawState_RED(2),drawState_RED(2)+Vec_RED(2)],[drawState_RED(1),drawState_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drawState_BLACK(2),drawState_BLACK(2)+Vec_BLACK(2)],[drawState_BLACK(1),drawState_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(app.ExperimentPreview,[drawState_BLUE(2),drawState_BLUE(2)+Vec_BLUE(2)],[drawState_BLUE(1),drawState_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                app.REDFigLink1 = line(app.ExperimentPreview,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(app.ExperimentPreview,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(app.ExperimentPreview,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(app.ExperimentPreview,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(app.ExperimentPreview,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(app.ExperimentPreview,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(app.ExperimentPreview,ree(1,1),ree(1,2),'filled','k');
                
            end

            % Ensure drag callbacks are assigned to newly created objects
            if isgraphics(app.REDFigHdl)
                app.REDFigHdl.ButtonDownFcn = @(src, event) startDragging(app, src, event);
            end
            if isgraphics(app.BLACKFigHdl)
                app.BLACKFigHdl.ButtonDownFcn = @(src, event) startDragging(app, src, event);
            end
            if isgraphics(app.BLUEFigHdl)
                app.BLUEFigHdl.ButtonDownFcn = @(src, event) startDragging(app, src, event);
            end

        end


    end
    
    methods (Access = public)
        
        function [] = ExternalGUIReset(app)

            app.PopulateGUI();
            
        end
    end
    
    methods (Access = private)
        
        function [spacecraft] = DrawSpacecraft(~,param)

            cx = param(1);
            cy = param(2);
            theta = param(5) - pi/2;
            r = param(3) / 2;

            sc = [cx,   cy;
            cx+r, cy;
            cx+r, cy+r;
            cx-r, cy+r;
            cx-r, cy-r;
            cx+r, cy-r;
            cx+r, cy];
        
            % Transform the spacecraft to be at the origin in order to apply the
            % rotation matrix
            sccenter = [ones(1,length(sc))*cx; ones(1,length(sc))*cy].' ;
            scbf = sc - sccenter;
            scbf(:,1) = -scbf(:,1); % Need to flip shape across y-axis
            
            % Define a rotation matrix based on the target orientation
            rotmat = [cos(theta), -sin(theta); 
                      sin(theta), cos(theta)];

            % Apply the rotation matrix to each of the points
            scbf_rot = zeros(size(scbf));
            for i=1:length(sc)
                scbf_rot(i,:) = rotmat*scbf(i,:).';
            end
            
            % Transform the spacecraft back to its correct position at cx,cy
            spacecraft = scbf_rot + sccenter;
        end

        function startDragging(app, src, event)
            % Get current mouse position in axes coordinates
            ax = app.ExperimentPreview;
            mousePos = ax.CurrentPoint(1, 1:2);
            
            % Check which object is clicked
            hObjs = [app.REDFigHdl, app.BLACKFigHdl, app.BLUEFigHdl];
            for hObj = hObjs
                if isgraphics(hObj)
                    xData = hObj.XData;
                    yData = hObj.YData;
                    if inpolygon(mousePos(1), mousePos(2), xData, yData)
                        % Start dragging this object
                        app.DraggingObjectHandle = hObj;
                        app.DraggingOffset = mousePos - [mean(xData), mean(yData)];
                        return;
                    end
                end
            end
        end

        function updateDragging(app, src, event)
            if ~isempty(app.DraggingObjectHandle)
                % Get current mouse position
                ax = app.ExperimentPreview;
                mousePos = ax.CurrentPoint(1, 1:2);
                
                % Update object position
                newPos = mousePos - app.DraggingOffset;
                dx = newPos(1) - mean(app.DraggingObjectHandle.XData);
                dy = newPos(2) - mean(app.DraggingObjectHandle.YData);
                app.DraggingObjectHandle.XData = app.DraggingObjectHandle.XData + dx;
                app.DraggingObjectHandle.YData = app.DraggingObjectHandle.YData + dy;
                
                % Update related text boxes
                if app.DraggingObjectHandle == app.REDFigHdl
                    app.REDInitialX.Value = mean(app.DraggingObjectHandle.XData);
                    app.REDInitialY.Value = mean(app.DraggingObjectHandle.YData);
                elseif app.DraggingObjectHandle == app.BLACKFigHdl
                    app.BLACKInitialX.Value = mean(app.DraggingObjectHandle.XData);
                    app.BLACKInitialY.Value = mean(app.DraggingObjectHandle.YData);
                elseif app.DraggingObjectHandle == app.BLUEFigHdl
                    app.BLUEInitialX.Value = mean(app.DraggingObjectHandle.XData);
                    app.BLUEInitialY.Value = mean(app.DraggingObjectHandle.YData);
                end
            end
        end

        function stopDragging(app, src, event)
            app.DraggingObjectHandle = [];
            app.DraggingOffset = [];
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PopulateGUI(app)
            app.MainGUI = GUI_v4_1_Main;
            
            app.REDInitialX.Value         = 2.5;
            app.REDInitialY.Value         = 1.25;      
            app.REDInitialTh.Value        = 0*180/pi;      

            app.BLACKInitialX.Value       = 1.5;
            app.BLACKInitialY.Value       = 1.25;      
            app.BLACKInitialTh.Value      = 4.7124*180/pi;    

            app.BLUEInitialX.Value        = 0.5;
            app.BLUEInitialY.Value        = 1.25;      
            app.BLUEInitialTh.Value       = 0*180/pi;

            app.ARMInitialQ1.Value        = pi/2*180/pi;
            app.ARMInitialQ2.Value        = pi/2*180/pi;
            app.ARMInitialQ3.Value        = 0*180/pi;

            app.REDStartX.Value           = 2.5;
            app.REDStartY.Value           = 1.25;      
            app.REDStartTh.Value          = 0*180/pi;      

            app.BLACKStartX.Value         = 1.5;
            app.BLACKStartY.Value         = 1.25;      
            app.BLACKStartTh.Value        = 4.7124*180/pi;    

            app.BLUEStartX.Value          = 0.5;
            app.BLUEStartY.Value          = 1.25;      
            app.BLUEStartTh.Value         = 0*180/pi;          

            app.REDHomeX.Value           = app.xLength/2+0.9;
            app.REDHomeY.Value           = app.yLength/2;      
            app.REDHomeTh.Value          = pi*180/pi;      

            app.BLACKHomeX.Value         = app.xLength/2;
            app.BLACKHomeY.Value         = app.yLength/2;      
            app.BLACKHomeTh.Value        = 0*180/pi;    

            app.BLUEHomeX.Value          = app.xLength/2-0.9;
            app.BLUEHomeY.Value          = app.yLength/2;      
            app.BLUEHomeTh.Value         = pi*180/pi;                 

            app.UpdateInitialConditions();

            % Assign callbacks for dragging functionality
            app.ExperimentPreview.ButtonDownFcn = @(src, event) startDragging(app, src, event);
            app.SPOT41InitialConditionsUIFigure.WindowButtonMotionFcn = @(src, event) updateDragging(app, src, event);
            app.SPOT41InitialConditionsUIFigure.WindowButtonUpFcn = @(src, event) stopDragging(app, src, event);          
        end

        % Close request function: SPOT41InitialConditionsUIFigure
        function SPOT41InitialConditionsUIFigureCloseRequest(app, event)
            %delete(app)   
            app.SPOT41InitialConditionsUIFigure.Visible = "off";
        end

        % Value changed function: ARMInitialQ1, ARMInitialQ2, 
        % ...and 28 other components
        function REDInitialXValueChanged(app, event)
            app.UpdateInitialConditions();
        end

        % Button pushed function: ShowDropLocationButton
        function ShowDropLocationButtonPushed(app, event)
            platformSelection = evalin('base','platformSelection');
            app.AddPlatformViz(platformSelection,app.drop_states_RED,app.drop_states_BLACK,app.drop_states_BLUE,app.drop_states_ARM);
        end

        % Button pushed function: ShowInitialLocationButton
        function ShowInitialLocationButtonPushed(app, event)
            platformSelection = evalin('base','platformSelection');
            app.AddPlatformViz(platformSelection,app.init_states_RED,app.init_states_BLACK,app.init_states_BLUE,app.drop_states_ARM);
        end

        % Button pushed function: ShowHomeLocationButton
        function ShowHomeLocationButtonPushed(app, event)
            platformSelection = evalin('base','platformSelection');
            app.AddPlatformViz(platformSelection,app.home_states_RED,app.home_states_BLACK,app.home_states_BLUE,app.drop_states_ARM);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SPOT41InitialConditionsUIFigure = uifigure('Visible', 'off');
            app.SPOT41InitialConditionsUIFigure.Name = 'SPOT 4.1 Initial Conditions';
            app.SPOT41InitialConditionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @SPOT41InitialConditionsUIFigureCloseRequest, true);
            app.GridLayout25 = uigridlayout(app.SPOT41InitialConditionsUIFigure);
            app.SetInitialandGoalPositionsforPlatformsPanel = uipanel(app.GridLayout25);
            app.SetInitialandGoalPositionsforPlatformsPanel.Title = 'Set Initial and Goal Positions for Platforms';
            app.GridLayout24_2 = uigridlayout(app.SetInitialandGoalPositionsforPlatformsPanel);
            app.mathrmDrop_REDLabel = uilabel(app.GridLayout24_2);
            app.mathrmDrop_REDLabel.Interpreter = 'latex';
            app.mathrmDrop_REDLabel.Text = '$\mathrm{Drop_{RED}}$:';
            app.REDInitialX = uieditfield(app.GridLayout24_2, 'numeric');
            app.REDInitialX.ValueDisplayFormat = '%.2f';
            app.REDInitialX.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.REDInitialX.Tooltip = {'Set the RED drop location (x). The drop location is only relevant for simulations.'};
            app.REDInitialX.Value = 100;
            app.REDInitialY = uieditfield(app.GridLayout24_2, 'numeric');
            app.REDInitialY.ValueDisplayFormat = '%.2f';
            app.REDInitialY.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.REDInitialY.Tooltip = {'Set the RED drop location (y). The drop location is only relevant for simulations.'};
            app.REDInitialY.Value = 100;
            app.REDInitialTh = uieditfield(app.GridLayout24_2, 'numeric');
            app.REDInitialTh.ValueDisplayFormat = '%.2f';
            app.REDInitialTh.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.REDInitialTh.Tooltip = {'Set the RED drop location (attitude). The drop location is only relevant for simulations.'};
            app.REDInitialTh.Value = 100;
            app.DurPhase0Units_8 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_8.Text = 'm';
            app.DurPhase0Units_30 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_30.Text = 'm';
            app.DurPhase0Units_31 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_31.Interpreter = 'latex';
            app.DurPhase0Units_31.Text = '$\mathbf{\degree}$';
            app.mathrmXLabel = uilabel(app.GridLayout24_2);
            app.mathrmXLabel.Interpreter = 'latex';
            app.mathrmXLabel.Text = '$\mathrm{X}$';
            app.mathrmYLabel = uilabel(app.GridLayout24_2);
            app.mathrmYLabel.Interpreter = 'latex';
            app.mathrmYLabel.Text = '$\mathrm{Y}$';
            app.mathrmtheta_baseLabel = uilabel(app.GridLayout24_2);
            app.mathrmtheta_baseLabel.Interpreter = 'latex';
            app.mathrmtheta_baseLabel.Text = '$\mathrm{\theta_{base}}$';
            app.Label = uilabel(app.GridLayout24_2);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout24_2);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout24_2);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.GridLayout24_2);
            app.Label_4.Text = '';
            app.mathrmInitial_REDLabel = uilabel(app.GridLayout24_2);
            app.mathrmInitial_REDLabel.Interpreter = 'latex';
            app.mathrmInitial_REDLabel.Text = '$\mathrm{Initial_{RED}}$:';
            app.REDStartX = uieditfield(app.GridLayout24_2, 'numeric');
            app.REDStartX.ValueDisplayFormat = '%.2f';
            app.REDStartX.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.REDStartX.Tooltip = {'Set the RED initial location (x). '};
            app.REDStartX.Value = 100;
            app.REDStartTh = uieditfield(app.GridLayout24_2, 'numeric');
            app.REDStartTh.ValueDisplayFormat = '%.2f';
            app.REDStartTh.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.REDStartTh.Tooltip = {'Set the RED initial location (attitude). '};
            app.REDStartTh.Value = 100;
            app.REDStartY = uieditfield(app.GridLayout24_2, 'numeric');
            app.REDStartY.ValueDisplayFormat = '%.2f';
            app.REDStartY.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.REDStartY.Tooltip = {'Set the RED initial location (y). '};
            app.REDStartY.Value = 100;
            app.DurPhase0Units_38 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_38.Text = 'm';
            app.DurPhase0Units_39 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_39.Text = 'm';
            app.mathrmInitial_BLALabel = uilabel(app.GridLayout24_2);
            app.mathrmInitial_BLALabel.Interpreter = 'latex';
            app.mathrmInitial_BLALabel.Text = '$\mathrm{Initial_{BLA}}$:';
            app.BLACKStartX = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLACKStartX.ValueDisplayFormat = '%.2f';
            app.BLACKStartX.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLACKStartX.Tooltip = {'Set the BLACK initial location (x).'};
            app.BLACKStartX.Value = 100;
            app.DurPhase0Units_41 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_41.Text = 'm';
            app.BLACKStartY = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLACKStartY.ValueDisplayFormat = '%.2f';
            app.BLACKStartY.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLACKStartY.Tooltip = {'Set the BLACK initial location (y).'};
            app.BLACKStartY.Value = 100;
            app.DurPhase0Units_42 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_42.Text = 'm';
            app.BLACKStartTh = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLACKStartTh.ValueDisplayFormat = '%.2f';
            app.BLACKStartTh.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLACKStartTh.Tooltip = {'Set the BLACK initial location (attitude).'};
            app.BLACKStartTh.Value = 100;
            app.mathrmHome_REDLabel = uilabel(app.GridLayout24_2);
            app.mathrmHome_REDLabel.Interpreter = 'latex';
            app.mathrmHome_REDLabel.Text = '$\mathrm{Home_{RED}}$:';
            app.REDHomeX = uieditfield(app.GridLayout24_2, 'numeric');
            app.REDHomeX.ValueDisplayFormat = '%.2f';
            app.REDHomeX.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.REDHomeX.Tooltip = {'Set the RED home location (x). '};
            app.REDHomeX.Value = 100;
            app.REDHomeY = uieditfield(app.GridLayout24_2, 'numeric');
            app.REDHomeY.ValueDisplayFormat = '%.2f';
            app.REDHomeY.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.REDHomeY.Tooltip = {'Set the RED home location (y). '};
            app.REDHomeY.Value = 100;
            app.DurPhase0Units_47 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_47.Text = 'm';
            app.DurPhase0Units_48 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_48.Text = 'm';
            app.mathrmHome_BLALabel = uilabel(app.GridLayout24_2);
            app.mathrmHome_BLALabel.Interpreter = 'latex';
            app.mathrmHome_BLALabel.Text = '$\mathrm{Home_{BLA}}$:';
            app.BLACKHomeX = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLACKHomeX.ValueDisplayFormat = '%.2f';
            app.BLACKHomeX.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLACKHomeX.Tooltip = {'Set the BLACK home location (x).'};
            app.BLACKHomeX.Value = 100;
            app.DurPhase0Units_50 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_50.Text = 'm';
            app.BLACKHomeY = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLACKHomeY.ValueDisplayFormat = '%.2f';
            app.BLACKHomeY.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLACKHomeY.Tooltip = {'Set the BLACK home location (y).'};
            app.BLACKHomeY.Value = 100;
            app.DurPhase0Units_51 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_51.Text = 'm';
            app.BLACKHomeTh = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLACKHomeTh.ValueDisplayFormat = '%.2f';
            app.BLACKHomeTh.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLACKHomeTh.Tooltip = {'Set the BLACK home location (attitude).'};
            app.BLACKHomeTh.Value = 100;
            app.mathrmHome_BLULabel = uilabel(app.GridLayout24_2);
            app.mathrmHome_BLULabel.Interpreter = 'latex';
            app.mathrmHome_BLULabel.Text = '$\mathrm{Home_{BLU}}$:';
            app.BLUEHomeX = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLUEHomeX.ValueDisplayFormat = '%.2f';
            app.BLUEHomeX.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLUEHomeX.Tooltip = {'Set the BLUE home location (x).'};
            app.BLUEHomeX.Value = 100;
            app.DurPhase0Units_53 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_53.Text = 'm';
            app.BLUEHomeY = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLUEHomeY.ValueDisplayFormat = '%.2f';
            app.BLUEHomeY.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLUEHomeY.Tooltip = {'Set the BLUE home location (y).'};
            app.BLUEHomeY.Value = 100;
            app.DurPhase0Units_54 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_54.Text = 'm';
            app.BLUEHomeTh = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLUEHomeTh.ValueDisplayFormat = '%.2f';
            app.BLUEHomeTh.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLUEHomeTh.Tooltip = {'Set the BLUE home location (attitude).'};
            app.BLUEHomeTh.Value = 100;
            app.REDHomeTh = uieditfield(app.GridLayout24_2, 'numeric');
            app.REDHomeTh.ValueDisplayFormat = '%.2f';
            app.REDHomeTh.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.REDHomeTh.Tooltip = {'Set the RED initial location (attitude). '};
            app.REDHomeTh.Value = 100;
            app.mathrmq_1Label = uilabel(app.GridLayout24_2);
            app.mathrmq_1Label.Interpreter = 'latex';
            app.mathrmq_1Label.Text = '$\mathrm{q_1}$';
            app.mathrmq_2Label = uilabel(app.GridLayout24_2);
            app.mathrmq_2Label.Interpreter = 'latex';
            app.mathrmq_2Label.Text = '$\mathrm{q_2}$';
            app.mathrmq_3Label = uilabel(app.GridLayout24_2);
            app.mathrmq_3Label.Interpreter = 'latex';
            app.mathrmq_3Label.Text = '$\mathrm{q_3}$';
            app.mathrmDrop_ARMLabel = uilabel(app.GridLayout24_2);
            app.mathrmDrop_ARMLabel.Interpreter = 'latex';
            app.mathrmDrop_ARMLabel.Text = '$\mathrm{Drop_{ARM}}$:';
            app.ARMInitialQ1 = uieditfield(app.GridLayout24_2, 'numeric');
            app.ARMInitialQ1.ValueDisplayFormat = '%.2f';
            app.ARMInitialQ1.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.ARMInitialQ1.Tooltip = {'Set the drop location for the robotic manipulator (joint #1).'};
            app.ARMInitialQ1.Value = 100;
            app.ARMInitialQ2 = uieditfield(app.GridLayout24_2, 'numeric');
            app.ARMInitialQ2.ValueDisplayFormat = '%.2f';
            app.ARMInitialQ2.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.ARMInitialQ2.Tooltip = {'Set the drop location for the robotic manipulator (joint #2).'};
            app.ARMInitialQ2.Value = 100;
            app.ARMInitialQ3 = uieditfield(app.GridLayout24_2, 'numeric');
            app.ARMInitialQ3.ValueDisplayFormat = '%.2f';
            app.ARMInitialQ3.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.ARMInitialQ3.Tooltip = {'Set the drop location for the robotic manipulator (joint #3).'};
            app.ARMInitialQ3.Value = 100;
            app.mathrmDrop_BLALabel = uilabel(app.GridLayout24_2);
            app.mathrmDrop_BLALabel.Interpreter = 'latex';
            app.mathrmDrop_BLALabel.Text = '$\mathrm{Drop_{BLA}}$:';
            app.BLACKInitialX = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLACKInitialX.ValueDisplayFormat = '%.2f';
            app.BLACKInitialX.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLACKInitialX.Tooltip = {'Set the BLACK drop location (x). The drop location is only relevant for simulations. '};
            app.BLACKInitialX.Value = 100;
            app.DurPhase0Units_32 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_32.Text = 'm';
            app.BLACKInitialY = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLACKInitialY.ValueDisplayFormat = '%.2f';
            app.BLACKInitialY.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLACKInitialY.Tooltip = {'Set the BLACK drop location (y). The drop location is only relevant for simulations. '};
            app.BLACKInitialY.Value = 100;
            app.DurPhase0Units_33 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_33.Text = 'm';
            app.BLACKInitialTh = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLACKInitialTh.ValueDisplayFormat = '%.2f';
            app.BLACKInitialTh.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLACKInitialTh.Tooltip = {'Set the BLACK drop location (attitude). The drop location is only relevant for simulations. '};
            app.BLACKInitialTh.Value = 100;
            app.mathrmDrop_BLULabel = uilabel(app.GridLayout24_2);
            app.mathrmDrop_BLULabel.Interpreter = 'latex';
            app.mathrmDrop_BLULabel.Text = '$\mathrm{Drop_{BLU}}$:';
            app.BLUEInitialX = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLUEInitialX.ValueDisplayFormat = '%.2f';
            app.BLUEInitialX.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLUEInitialX.Tooltip = {'Set the BLUE drop location (x). The drop location is only relevant for simulations. '};
            app.BLUEInitialX.Value = 100;
            app.DurPhase0Units_35 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_35.Text = 'm';
            app.BLUEInitialY = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLUEInitialY.ValueDisplayFormat = '%.2f';
            app.BLUEInitialY.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLUEInitialY.Tooltip = {'Set the BLUE drop location (y). The drop location is only relevant for simulations. '};
            app.BLUEInitialY.Value = 100;
            app.DurPhase0Units_36 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_36.Text = 'm';
            app.BLUEInitialTh = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLUEInitialTh.ValueDisplayFormat = '%.2f';
            app.BLUEInitialTh.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLUEInitialTh.Tooltip = {'Set the BLUE drop location (attitude). The drop location is only relevant for simulations. '};
            app.BLUEInitialTh.Value = 100;
            app.mathrmInitial_BLULabel = uilabel(app.GridLayout24_2);
            app.mathrmInitial_BLULabel.Interpreter = 'latex';
            app.mathrmInitial_BLULabel.Text = '$\mathrm{Initial_{BLU}}$:';
            app.BLUEStartX = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLUEStartX.ValueDisplayFormat = '%.2f';
            app.BLUEStartX.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLUEStartX.Tooltip = {'Set the BLUE initial location (x).'};
            app.BLUEStartX.Value = 100;
            app.DurPhase0Units_44 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_44.Text = 'm';
            app.BLUEStartY = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLUEStartY.ValueDisplayFormat = '%.2f';
            app.BLUEStartY.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLUEStartY.Tooltip = {'Set the BLUE initial location (y).'};
            app.BLUEStartY.Value = 100;
            app.DurPhase0Units_45 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_45.Text = 'm';
            app.BLUEStartTh = uieditfield(app.GridLayout24_2, 'numeric');
            app.BLUEStartTh.ValueDisplayFormat = '%.2f';
            app.BLUEStartTh.ValueChangedFcn = createCallbackFcn(app, @REDInitialXValueChanged, true);
            app.BLUEStartTh.Tooltip = {'Set the BLUE initial location (attitude).'};
            app.BLUEStartTh.Value = 100;
            app.Image = uiimage(app.GridLayout24_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Line.png');
            app.Image_2 = uiimage(app.GridLayout24_2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Line.png');
            app.Image_3 = uiimage(app.GridLayout24_2);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Line.png');
            app.DurPhase0Units_55 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_55.Interpreter = 'latex';
            app.DurPhase0Units_55.Text = '$\mathbf{\degree}$';
            app.DurPhase0Units_56 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_56.Interpreter = 'latex';
            app.DurPhase0Units_56.Text = '$\mathbf{\degree}$';
            app.DurPhase0Units_57 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_57.Interpreter = 'latex';
            app.DurPhase0Units_57.Text = '$\mathbf{\degree}$';
            app.DurPhase0Units_58 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_58.Interpreter = 'latex';
            app.DurPhase0Units_58.Text = '$\mathbf{\degree}$';
            app.DurPhase0Units_59 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_59.Interpreter = 'latex';
            app.DurPhase0Units_59.Text = '$\mathbf{\degree}$';
            app.DurPhase0Units_60 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_60.Interpreter = 'latex';
            app.DurPhase0Units_60.Text = '$\mathbf{\degree}$';
            app.DurPhase0Units_61 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_61.Interpreter = 'latex';
            app.DurPhase0Units_61.Text = '$\mathbf{\degree}$';
            app.DurPhase0Units_62 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_62.Interpreter = 'latex';
            app.DurPhase0Units_62.Text = '$\mathbf{\degree}$';
            app.DurPhase0Units_63 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_63.Interpreter = 'latex';
            app.DurPhase0Units_63.Text = '$\mathbf{\degree}$';
            app.DurPhase0Units_64 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_64.Interpreter = 'latex';
            app.DurPhase0Units_64.Text = '$\mathbf{\degree}$';
            app.DurPhase0Units_65 = uilabel(app.GridLayout24_2);
            app.DurPhase0Units_65.Interpreter = 'latex';
            app.DurPhase0Units_65.Text = '$\mathbf{\degree}$';
            app.VisualizerPanel = uipanel(app.GridLayout25);
            app.VisualizerPanel.Title = 'Visualizer';
            app.GridLayout26 = uigridlayout(app.VisualizerPanel);
            app.ExperimentPreview = uiaxes(app.GridLayout26);
            xlabel(app.ExperimentPreview, 'Y-Coordinate (m)')
            ylabel(app.ExperimentPreview, 'X-Coordinate (m)')
            app.ShowHomeLocationButton = uibutton(app.GridLayout26, 'push');
            app.ShowHomeLocationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHomeLocationButtonPushed, true);
            app.ShowHomeLocationButton.Tooltip = {'Show the home location for the active hardware.'};
            app.ShowHomeLocationButton.Text = {'Show'; 'Home Location'};
            app.ShowInitialLocationButton = uibutton(app.GridLayout26, 'push');
            app.ShowInitialLocationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowInitialLocationButtonPushed, true);
            app.ShowInitialLocationButton.Tooltip = {'Show the initial location for the active hardware.'};
            app.ShowInitialLocationButton.Text = {'Show'; 'Initial Location'};
            app.ShowDropLocationButton = uibutton(app.GridLayout26, 'push');
            app.ShowDropLocationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDropLocationButtonPushed, true);
            app.ShowDropLocationButton.Tooltip = {'Show the drop location for the active hardware.'};
            app.ShowDropLocationButton.Text = {'Show'; 'Drop Location'};
            % Show the figure after all components are created
            app.SPOT41InitialConditionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v4_1_InitialConditions

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SPOT41InitialConditionsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @PopulateGUI)
            else

                % Focus the running singleton app
                figure(runningApp.SPOT41InitialConditionsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SPOT41InitialConditionsUIFigure)
        end
    end
end